classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MultiviewwindowButton          matlab.ui.control.Button
        ReplayButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        RunConstructionButton          matlab.ui.control.Button
        Inputfor3rdcircleButton        matlab.ui.control.Button
        Inputfor1stand2ndcircleButton  matlab.ui.control.Button
        RevealButton                   matlab.ui.control.Button
        HideButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %%
    end
    
    properties (Access = private)
        a,b,c % plots for graph
        x,y, x1, y1 % input for plots
        xout, yout
        radius, xCent, yCent, xCoord, yCoord
        xCoord1, yCoord1, dist, xCent1, yCent1
        xout1, yout1, holdp1x1, holdp1y1
        xout2, yout2, xout3, yout3
        holdp1x2, holdp1y2
        arrmat, hideL1, hideL2, hideL3, filename
        viewst, loadm, answer, xCoord3, yCoord3
        p1x, p1y
    end
    
    methods (Access = public)
        
                     function value = r8_epsilon ( dummy )

%*****************************************************************************80
%
%% R8_EPSILON returns the R8 roundoff unit.
%
%  Discussion:
%
%    The roundoff unit is a number R which is a power of 2 with the 
%    property that, to the precision of the computer's arithmetic,
%      1 < 1 + R
%    but 
%      1 = ( 1 + R / 2 )
%
%  Licensing:
%
%    This code is distributed under the GNU LGPL license.
%
%  Modified:
%
%    22 August 2004
%
%  Author:
%
%    John Burkardt
%
%  Parameters:
%
%    Input, double precision DUMMY, a dummy value.
%
%    Output, double precision VALUE, the roundoff unit.
%
  value = eps;

  return
end
    end
  
    
    methods (Access = private)
        
        function [ num_int, p ] = circle_imp_line_par_int_2d (~, r, center, x0, y0, f, g )

%% CIRCLE_IMP_LINE_PAR_INT_2D: ( implicit circle, parametric line ) intersection in 2D.
%
%  Discussion:
%
%    An implicit circle in 2D satisfies the equation:
%
%      ( X - CENTER(1) )**2 + ( Y - CENTER(2) )**2 = R**2
%
%    The parametric form of a line in 2D is:
%
%      X = X0 + F * T
%      Y = Y0 + G * T
%
%  Licensing:
%
%    This code is distributed under the GNU LGPL license.
%
%  Modified:
%
%    31 January 2005
%
%  Author:
%
%    John Burkardt
%
%  Parameters:
%
%    Input, real R, the radius of the circle.
%
%    Input, real CENTER(2), the center of the circle.
%
%    Input, real F, G, X0, Y0, the parametric parameters of 
%    the line.
%
%    Output, integer NUM_INT, the number of intersecting points found.
%    NUM_INT will be 0, 1 or 2.
%
%    Output, real P(2,NUM_INT), the intersecting points.
%
  dim_num = 2;
  p = [];

  root = r * r * ( f * f + g * g ) - ( f * ( center(2) - y0 ) ...
    - g * ( center(1) - x0 ) ).^2;

  if ( root < 0.0 )

    num_int = 0;

  elseif ( root == 0.0 )

    num_int = 1;

    t = ( f * ( center(1) - x0 ) + g * ( center(2) - y0 ) ) / ( f * f + g * g );
    p(1,1) = x0 + f * t;
    p(2,1) = y0 + g * t;

  elseif ( 0.0 < root )

    num_int = 2;

    t = ( ( f * ( center(1) - x0 ) + g * ( center(2) - y0 ) ) ...
      - sqrt ( root ) ) / ( f * f + g * g );

    p(1,1) = x0 + f * t;
    p(2,1) = y0 + g * t;

    t = ( ( f * ( center(1) - x0 ) + g * ( center(2) - y0 ) ) ...
      + sqrt ( root ) ) / ( f * f + g * g );

    p(1,2) = x0 + f * t;
    p(2,2) = y0 + g * t;

  end

  return
end
        
        function [ f, g, x0, y0 ] = line_exp2par_2d (~,p1, p2 )

%% LINE_EXP2PAR_2D converts a line from explicit to parametric form in 2D.
%
%  Discussion:
%
%    The explicit form of a line in 2D is:
%
%      ( P1, P2 ) = ( (X1,Y1), (X2,Y2) ).
%
%    The parametric form of a line in 2D is:
%
%      X = X0 + F * T
%      Y = Y0 + G * T
%
%    We normalize by choosing F*F+G*G=1 and 0 <= F.
%
%  Licensing:
%
%    This code is distributed under the GNU LGPL license.
%
%  Modified:
%
%    20 July 2006
%
%  Author:
%
%    John Burkardt
%
%  Parameters:
%
%    Input, real P1(2), P2(2), two points on the line.
%
%    Output, real F, G, X0, Y0, the parametric parameters
%    of the line.
%
  x0 = p1(1);
  y0 = p1(2);

  f = p2(1) - p1(1);
  g = p2(2) - p1(2);

  norm = sqrt ( f * f + g * g );

  if ( norm ~= 0.0 )
    f = f / norm;
    g = g / norm;
  end

  if ( f < 0.0 )
    f = -f;
    g = -g;
  end

  return
end
        
function [ t1, t2, pi ] = lines_par_int_2d (~, f1, g1, x1, y1, f2, g2, ...
  x2, y2 )

%% LINES_PAR_INT_2D determines where two parametric lines intersect in 2D.
%
%  Discussion:
%
%    The parametric form of a line in 2D is:
%
%      X = X0 + F * T
%      Y = Y0 + G * T
%
%    We normalize by choosing F*F+G*G=1 and 0 <= F.
%
%  Licensing:
%
%    This code is distributed under the GNU LGPL license.
%
%  Modified:
%
%    27 February 2005
%
%  Author:
%
%    John Burkardt
%
%  Reference:
%
%    Adrian Bowyer and John Woodwark,
%    A Programmer's Geometry,
%    Butterworths, 1983.
%
%  Parameters:
%
%    Input, real F1, G1, X1, Y1, define the first parametric line.
%
%    Input, real F2, G2, X2, Y2, define the second parametric line.
%
%    Output, real T1, T2, the T parameters on the first and second
%    lines of the intersection point.
%
%    Output, real PI(2), the intersection point.
%
  det = f2 * g1 - f1 * g2;

  if ( det == 0.0 )
    t1 = 0.0;
    t2 = 0.0;
    pi = [];
  else
    t1 = ( f2 * ( y2 - y1 ) - g2 * ( x2 - x1 ) ) / det;
    t2 = ( f1 * ( y2 - y1 ) - g1 * ( x2 - x1 ) ) / det;
    pi(1) = x1 + f1 * t1;
    pi(2) = y1 + g1 * t1;
  end

  return
end


        function P = linesegintersect(P1, P2, P3, P4)
            % We assume P1, P2, P3 and P4 are column vectors
            P1P2 = P2 - P1;
            P3P4 = P4 - P3;
            [a1 b1 c1] = line_par2imp_2d(P1P2(1), P1P2(2), P1(1), P1(2));
            [a2 b2 c2] = line_par2imp_2d(P3P4(1), P3P4(2), P3(1), P3(2));
            [ival, P] = lines_imp_int_2d (a1, b1, c1, a2, b2, c2);
            P = P'; % convert from row vector to column vector
            if ival ~= 1
            P = [];
            else
            if dot(P - P1, P2 - P) < 0
            P = [];
            end
            end
            end

         
        function [ num_int, p ] = circles_imp_int_2d (~, r1, center1, r2, center2 )

%% CIRCLES_IMP_INT_2D: finds the intersection of two implicit circles in 2D.
%
%  Discussion:
%
%    Two circles can intersect in 0, 1, 2 or infinitely many points.
%
%    The 0 and 2 intersection cases are numerically robust; the 1 and
%    infinite intersection cases are numerically fragile.  The routine
%    uses a tolerance to try to detect the 1 and infinite cases.
%
%    An implicit circle in 2D satisfies the equation:
%
%      ( X - CENTER(1) )**2 + ( Y - CENTER(2) )**2 = R**2
%
%  Licensing:
%
%    This code is distributed under the GNU LGPL license.
%
%  Modified:
%
%    24 February 2005
%
%  Author:
%
%    John Burkardt
%
%  Parameters:
%
%    Input, real R1, the radius of the first circle.
%
%    Input, real CENTER1(2), the center of the first circle.
%
%    Input, real R2, the radius of the second circle.
%
%    Input, real CENTER2(2), the center of the second circle.
%
%    Output, integer NUM_INT, the number of intersecting points found.
%    NUM_INT will be 0, 1, 2 or 3.  3 indicates that there are an infinite
%    number of intersection points.
%
%    Output, real P(2,2), if NUM_INT is 1 or 2,
%    the coordinates of the intersecting points.
%
  dim_num = 2;

  tol = r8_epsilon ( );

  p(1:dim_num,1:2) = 0.0;;
%
%  Take care of the case in which the circles have the same center.
%
  t1 = ( abs ( center1(1) - center2(1) ) ...
       + abs ( center1(2) - center2(2) ) ) / 2.0;

  t2 = ( abs ( center1(1) ) + abs ( center2(1) ) ...
       + abs ( center1(2) ) + abs ( center2(2) ) + 1.0 ) / 5.0;

  if ( t1 <= tol * t2 )

    t1 = abs ( r1 - r2 );
    t2 = ( abs ( r1 ) + abs ( r2 ) + 1.0 ) / 3.0;

    if ( t1 <= tol * t2 )
      num_int = 3;
    else
      num_int = 0;
    end

    return

  end

  distsq = sum ( ( center1(1:2) - center2(1:2) ).^2 );

  root = 2.0 * ( r1 * r1 + r2 * r2 ) * distsq - distsq * distsq ...
    - ( r1 - r2 ) * ( r1 - r2 ) * ( r1 + r2 ) * ( r1 + r2 );

  if ( root < -tol )
    num_int = 0;
    return
  end

  sc1 = ( distsq - ( r2 * r2 - r1 * r1 ) ) / distsq;

  if ( root < tol )
    num_int = 1;
    p(1:dim_num,1) = center1(1:dim_num)' ...
      + 0.5 * sc1 * ( center2(1:dim_num) - center1(1:dim_num) )';
    return
  end

  sc2 = sqrt ( root ) / distsq;

  num_int = 2;

  p(1,1) = center1(1) + 0.5 * sc1 * ( center2(1) - center1(1) ) ...
                      - 0.5 * sc2 * ( center2(2) - center1(2) );
  p(2,1) = center1(2) + 0.5 * sc1 * ( center2(2) - center1(2) ) ...
                      + 0.5 * sc2 * ( center2(1) - center1(1) );

  p(1,2) = center1(1) + 0.5 * sc1 * ( center2(1) - center1(1) ) ...
                      + 0.5 * sc2 * ( center2(2) - center1(2) );
  p(2,2) = center1(2) + 0.5 * sc1 * ( center2(2) - center1(2) ) ...
                      - 0.5 * sc2 * ( center2(1) - center1(1) );

  return
end


%         final
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HideButton
        function HideButtonPushed(app, event)
            set(app.hideL1,'Visible','off');
            set(app.hideL2,'Visible','off');
            set(app.hideL3,'Visible','off');
        end

        % Button pushed function: RevealButton
        function RevealButtonPushed(app, event)
            set(app.hideL1,'Visible','on');
            set(app.hideL2,'Visible','on');
            set(app.hideL3,'Visible','on');
        end

        % Button pushed function: Inputfor1stand2ndcircleButton
        function Inputfor1stand2ndcircleButtonPushed(app, event)
            hold on;
            [app.x,app.y] = ginput(3);
            app.x = [app.x(1),app.x(2),app.x(3)];
            app.y = [app.y(1),app.y(2),app.y(3)];
            
        end

        % Button pushed function: Inputfor3rdcircleButton
        function Inputfor3rdcircleButtonPushed(app, event)
            [app.x1,app.y1] = ginput(3);
            app.x1 = [app.x1(1),app.x1(2),app.x1(3)];
            app.y1 = [app.y1(1),app.y1(2),app.y1(3)];
            
        end

        % Button pushed function: RunConstructionButton
        function RunConstructionButtonPushed(app, event)

            hold(app.UIAxes,"on");
        %   for replay conv all vals from file ->user input val
            
            if isprop(app.ReplayButton, 'Value') && event.Value == true
                app.viewst = true;
                if app.viewst == true
                app.x(1) = app.loadm(1,1);
                app.x(2) = app.loadm(1,2);
                app.x(3) = app.loadm(1,3);
                app.x1(1) = app.loadm(1,4);
                app.x1(2) = app.loadm(1,5);
                app.x1(3) = app.loadm(1,6);
                app.y(1) = app.loadm(2,1);
                app.y(2) = app.loadm(2,2);
                app.y(3) = app.loadm(2,3);
                app.y1(1) = app.loadm(2,4);
                app.y1(2) = app.loadm(2,5);
                app.y1(3) = app.loadm(2,6);            
                end
            end


            %            q1-3
            
             pauseno = 0.5;         
            %define theta range
%             theta = 0:0.01:2*pi;
            %define center coordinates and radius
            app.radius = sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2); 
            app.xCent = app.x(1);
            app.yCent = app.y(1);
             anim1 = animatedline(app.UIAxes,"Color","#77AC30","LineWidth",2);
             for theta = 0:0.01:2*pi
            %define parametric equation
            app.xCoord = app.xCent + app.radius*cos(theta);
            app.yCoord = app.yCent + app.radius*sin(theta);
            %plot 1st circle 
            addpoints(anim1, app.xCoord, app.yCoord);
            drawnow
            grid on;
             end
%             set(app.a,'Visible','on');
            plot(app.UIAxes,app.x(1),app.y(1), 'rx', 'LineWidth',2);
            hold(app.UIAxes,"on");
            axis equal
            pause(pauseno)
            
            %find shortest dist
            app.dist = sqrt((app.x(3)-app.x(1))^2+(app.y(3)-app.y(1))^2) - app.radius;
            app.xCent1 = app.x(3);
            app.yCent1 = app.y(3);
            anim2 = animatedline(app.UIAxes,"Color","#0072BD","LineWidth",2);
             for theta = 0:0.01:2*pi
            app.xCoord1 = app.xCent1 + app.dist*cos(theta);
            app.yCoord1 = app.yCent1 + app.dist*sin(theta); 
            %plot 2nd circle 
            addpoints(anim2, app.xCoord1, app.yCoord1);
            drawnow
             end
            plot(app.UIAxes,app.x(3),app.y(3), 'gx', 'LineWidth',2);
            axis equal
            pause(pauseno)
%             app.b = plot(app.UIAxes,app.xCoord1,app.yCoord1, 'g', 'LineWidth',2);
            axis equal
            pause(pauseno)
            
            
            %q4.1
            %plot initial user sel p
            plot(app.UIAxes,app.x1(1),app.y1(1),'bx', 'LineWidth',2);
            axis equal
            plot(app.UIAxes,app.x1(2),app.y1(2),'bx', 'LineWidth',2);
            axis equal
            plot(app.UIAxes,app.x1(3),app.y1(3),'bx', 'LineWidth',2);
            axis equal
            pause(pauseno)
            %dist-> which circle p1 is closest to
            distp1c1 = sqrt((app.x1(1)-app.x(1))^2+(app.y1(1)-app.y(1))^2) - app.radius;
            distp1c2 = sqrt((app.x1(1)-app.x(3))^2+(app.y1(1)-app.y(3))^2) - app.dist;
            distp2c1 = sqrt((app.x1(2)-app.x(1))^2+(app.y1(2)-app.y(1))^2) - app.radius;
            distp2c2 = sqrt((app.x1(2)-app.x(3))^2+(app.y1(2)-app.y(3))^2) - app.dist;
           
            %line of p1 to centre
            %plot(app.UIAxes,[app.x1(1) app.x(1)],[app.y1(1) app.y(1)],'*-');

            P1 = [app.x1(1),app.y1(1)];
            P2 = [app.x(1),app.y(1)];
            P3 = [app.x(3),app.y(3)];
            P4 = [app.x1(2),app.y1(2)];
             %convert and use in funct
             [f, g, x0, y0] = app.line_exp2par_2d( P1 , P2 );
             [f1, g1, x01, y01] = app.line_exp2par_2d( P1 , P3 );

            %closest point on its circumference -> get line intersect
                cnter = [app.x(1), app.y(1)];
                cnter1 = [app.x(3), app.y(3)];
             [ num_int, point ] = app.circle_imp_line_par_int_2d ( app.radius, cnter, x0, y0, f, g);
             [ num_int1, point1 ] = app.circle_imp_line_par_int_2d ( app.dist, cnter1, x01, y01, f1, g1);

%             find closest dist p1 -> points on circumf
            ax = [app.x1(1) point(1,1)];
            bx = [app.y1(1) point(2,1)];
            d = norm(ax-bx);
            
            cx = [app.x1(1) point(1,2)];
            dx = [app.y1(1) point(2,2)];
            d1 = norm(cx-dx);

            %find closest dist p1 -> points on circumf
            ex = [app.x1(1) point1(1,1)];
            fx = [app.y1(1) point1(2,1)];
            d2 = norm(ex-fx);
            
            gx = [app.x1(1) point1(1,2)];
            hx = [app.y1(1) point1(2,2)];
            d3 = norm(gx-hx);

            %determine which circle p1 is closest to
            %if p1 closer to c1 than c2
            app.holdp1x1 = 0;
            app.holdp1y1 = 0;


            %q4.1.2
            %sec p, repeat
            %report err if first p and sec p lie on same circle
             [f2, g2, x02, y02] = app.line_exp2par_2d( P4 , P2 );
             [f3, g3, x03, y03] = app.line_exp2par_2d( P4 , P3 );
             [ num_int2, point2 ] = app.circle_imp_line_par_int_2d ( app.radius, cnter, x02, y02, f2, g2);
             [ num_int3, point3 ] = app.circle_imp_line_par_int_2d ( app.dist, cnter1, x03, y03, f3, g3);

            %find closest dist p2 -> points on circumf
            ix = [app.x1(2) point2(1,1)];
            jx = [app.y1(2) point2(2,1)];
            d4 = norm(ix-jx);
            
            kx = [app.x1(2) point2(1,2)];
            lx = [app.y1(2) point2(2,2)];
            d5 = norm(kx-lx);

            %find closest dist p2 -> points on circumf
            mx = [app.x1(2) point3(1,1)];
            nx = [app.y1(2) point3(2,1)];
            d6 = norm(mx-nx);
            
            ox = [app.x1(2) point3(1,2)];
            px = [app.y1(2) point3(2,2)];
            d7 = norm(ox-px); 
            
            app.holdp1x2 = 0;
            app.holdp1y2 = 0;

            %q4.1-2 ->alg

            if (distp1c1 < distp1c2 && distp2c1 < distp2c2) || (distp1c2 < distp1c1 && distp2c2 < distp2c1)
                opts = struct('WindowStyle','modal',... 
                'Interpreter','tex');
                errormsg = errordlg('Try again - Both plots nearest to same circle',...
                'Equation Error', opts);
            end
            
            if (distp1c1 < distp1c2)
                %if p1 closer to m1 than m2
                if (d <= d1)
                     %holdp1 = plot(app.UIAxes,app.xout(1),app.yout(1),'o', 'LineWidth',2);
                     app.holdp1x1 = point(1,1);
                     app.holdp1y1 = point(2,1);                     
                else
                     %holdp1 = plot(app.UIAxes,app.xout(2),app.yout(2),'o', 'LineWidth',2);
                     app.holdp1x1 = point(1,2);
                     app.holdp1y1 = point(2,2);  
                end
            else
                if (d2<=d3)
                    %holdp1 = plot(app.UIAxes,app.xout1(1),app.yout1(1),'o', 'LineWidth',2);
                    app.holdp1x1 = point1(1,1);
                    app.holdp1y1 = point1(2,1);
                else
                    %holdp1 = plot(app.UIAxes,app.xout1(2),app.yout1(2),'o', 'LineWidth',2);
                    app.holdp1x1 = point1(1,2);
                    app.holdp1y1 = point1(2,2);
                end
            end
            plot(app.UIAxes,app.holdp1x1,app.holdp1y1,'bo', 'LineWidth',3);
 axis equal
            app.holdp1x2 = 0;
            app.holdp1y2 = 0;
            %if p2 closer to m1 than m2
            if (distp2c1 < distp2c2)
                if (d4 <= d5)
                     %holdp1 = plot(app.UIAxes,app.xout(1),app.yout(1),'o', 'LineWidth',2);
                     app.holdp1x2 = point2(1,1);
                     app.holdp1y2 = point2(2,1);                     
                else
                     %holdp1 = plot(app.UIAxes,app.xout(2),app.yout(2),'o', 'LineWidth',2);
                     app.holdp1x2 = point2(1,2);
                     app.holdp1y2 = point2(2,2);  
                end
            else
                if (d6<=d7)
                    %holdp1 = plot(app.UIAxes,app.xout1(1),app.yout1(1),'o', 'LineWidth',2);
                    app.holdp1x2 = point3(1,1);
                    app.holdp1y2 = point3(2,1);
                else
                    %holdp1 = plot(app.UIAxes,app.xout1(2),app.yout1(2),'o', 'LineWidth',2);
                    app.holdp1x2 = point3(1,2);
                    app.holdp1y2 = point3(2,2);
                end
            end
            plot(app.UIAxes,app.holdp1x2,app.holdp1y2,'bo', 'LineWidth',3);
axis equal
           
            %q4.2.1

            %make bisector -> 

            plot(app.UIAxes,[app.x(3) app.x(1)],[app.y(3) app.y(1)],'p-'); hold on
            axis equal
            % Find midpoint
             midX = (app.x(1)+ app.x(3))/2;
             midY = (app.y(1)+app.y(3))/2; 
            hold on;
            plot(app.UIAxes,midX, midY, 'o', 'LineWidth', 2);
            axis equal
            pause(pauseno)
            % Get the slope
            slope = (app.y(3)-app.y(1)) / (app.x(3)-app.x(1));
            % For perpendicular line, slope = -1/slope
            slope1 = -1/slope;
            ahold = [app.x(1) app.y(1)];
            bhold = [app.x(3) app.y(3)];
%             p1x = linspace(0 - norm( Xhold - Yhold ), midX*1.05, 10);
           xint = ( 0 - midX ) / slope1 + midY; %x intersection on y axis
            app.p1x = linspace(-norm( ahold - bhold ) , midX + norm( ahold - bhold )); % when bisector not infinite or slope = 0
            app.p1y = slope1 * (app.p1x - midX) + midY;
%             plot(app.UIAxes,[p1x, midX], [p1y, midY], '--', 'LineWidth', 2); 
%           xint = ( 0 - midX ) / slope1 + midY;
%         edge cases->
            if slope1 == 1 % If the bisector is infinite (y axis)
	            app.p1x = midY - norm( ahold - bhold ) : midY + norm( ahold - bhold ); % Get y vertically
                   app.p1x = zeros( 1, length(app.p1y) );
                    for i = 1:length(app.p1x)
        	            app.p1x(i) = midx; % midpoints x on the bisector
                    end
                    xint = midX;
            
            elseif slope1 == 0 % If the bisector is infinite-flat on (x axis)
	                app.p1x = midX - norm( ahold - bhold ) : midX + norm( ahold - bhold );
                      app.p1y = zeros( 1, length(app.p1x) );
                        for i = 1:length(app.p1y)
        		            app.p1y(i) = midY; 
                       end
                    xint = nan;
            end
             app.hideL1 = plot(app.UIAxes,app.p1x, app.p1y, '--', 'LineWidth', 2);  
              axis equal
pause(pauseno)

            %q4.2.2
%       step1:find minimum dist between p and line => perpendicular dist 
%    s2:use min dist as radius for circ s3: get insters of line and circ

             xc = app.x1(3); 
             yc = app.y1(3);
            theta1 = 0:0.01:2*pi;
%             dist1int = d = abs((a * x1 + b * y1 + c)) / (math.sqrt(a * a + b * b))
%              dist1int = abs(( (slope1 * xc) - (p1y(end) * yc) + midY)) / (sqrt(slope1 * slope1 + p1y(end) * p1y(end)));
            if slope1 >0 %error check +tive -tive slope
               dist1int = abs(( (slope1 * xc) - (app.p1y(end) * yc) + midY)) / (sqrt(slope1 * slope1 + app.p1y(end) * app.p1y(end)));
            elseif slope1 < 0 
               dist1int = abs(( (slope1 * xc) - (app.p1y(1) * yc) + midY)) / (sqrt(slope1 * slope1 + app.p1y(1) * app.p1y(1)));
            end

            xCentT = app.x1(3);
            yCentT = app.y1(3);
            xCoordT = xCentT + dist1int*cos(theta1);
            yCoordT = yCentT + dist1int*sin(theta1);
%             plot(app.UIAxes,xCoordT,yCoordT, 'b', 'LineWidth',2);

            P5 = [app.p1x(1),app.p1y(1)];
            P6 = [app.p1x(end),app.p1y(end)];
             %convert and use in funct
             [f4, g4, x04, y04] = app.line_exp2par_2d( P5 , P6 );

            %closest point on line from p-> get line intersect
                cnter3 = [xc, yc];
             [ num_int4, point4 ] = app.circle_imp_line_par_int_2d ( dist1int, cnter3, x04, y04, f4, g4);

            holdp2x1 = 0;
            holdp2y1 = 0;

             mx1 = [app.x1(3) point4(1,1)];
            nx1 = [app.y1(3) point4(2,1)];
            d6a = norm(mx1-nx1);
            
            ox1 = [app.x1(3) point4(1,2)];
            px1 = [app.y1(3) point4(2,2)];
            d7a = norm(ox1-px1);

                if (d6a < d7a)
                     holdp2x1 = point4(1,1);
                     holdp2y1 = point4(2,1);                     
                else
                     holdp2x1 = point4(1,2);
                     holdp2x1 = point4(2,2);  
                end

        plot(app.UIAxes,holdp2x1,holdp2y1,'bo', 'LineWidth',3);
axis equal
               pause(pauseno)                               
%                app.b = plot(app.UIAxes,app.xCoord1,app.yCoord1, 'g', 'LineWidth',2);
                                                            %------>
                                                            %rewrite
                                                            %comments 
                                                            %<-----
% q4.3.1
                    %make bisector for snapped p 1:2 -> 

            plot(app.UIAxes,[app.holdp1x1 app.holdp1x2],[app.holdp1y1 app.holdp1y2],'p-'); hold on
            pause(pauseno)
            % Find midpoint
             midX1 = (app.holdp1x1+ app.holdp1x2)/2;
             midY1 = (app.holdp1y1+app.holdp1y2)/2; 
            hold on;
%             plot(app.UIAxes,midX1, midY1, 'o', 'LineWidth', 2);
            axis equal
            % Get the slope
            slope2 = (app.holdp1y2-app.holdp1y1) / (app.holdp1x2-app.holdp1x1);
            % For perpendicular line, slope = -1/slope
            slope3 = -1/slope2;
            ahold1 = [app.holdp1x1 app.holdp1y1];
            bhold1 = [app.holdp1x2 app.holdp1y2];
           xint1 = ( 0 - midX1 ) / slope3 + midY1; %x intersection on y axis
            p1x1 = linspace(-norm( ahold1 - bhold1 ) , midX1 + norm( ahold1 - bhold1 )); % when bisector not infinite (1) and slope != 0
            p1y1 = slope3 * (p1x1 - midX1) + midY1;
%error check
            if slope3 == 1 % If the bisector is infinite (y axis)
	            p1x1 = midY1 - norm( ahold1 - bhold1 ) : midY1 + norm( ahold1 - bhold1 ); %  y vertical
                   p1x1 = zeros( 1, length(p1y1) );
                    for i = 1:length(p1x1)
        	            p1x1(i) = midx1; % midpoint's x on the bisector
                    end
                    xint2 = midX1;
            
            elseif slope3 == 0 % If the bisector is infinite-flat on (x axis)
	                p1x1 = midX1 - norm( ahold1 - bhold1 ) : midX1 + norm( ahold1 - bhold1 );
                      p1y1 = zeros( 1, length(p1x1) );
                        for i = 1:length(p1y1)
        		            p1y1(i) = midY1; 
                       end
                    xint2 = nan;
            end
            app.hideL2 = plot(app.UIAxes,p1x1, p1y1, 'r--', 'LineWidth', 2);  
              axis equal
              pause(pauseno)

%repeat for p 1:3
    plot(app.UIAxes,[app.holdp1x1 holdp2x1],[app.holdp1y1 holdp2y1],'p-'); hold on
            axis equal
            % Find midpoint
             midX2 = (app.holdp1x1+ holdp2x1)/2;
             midY2 = (app.holdp1y1+holdp2y1)/2; 
            hold on;
%             plot(app.UIAxes,midX2, midY2, 'o', 'LineWidth', 2);
            axis equal
            % Get the slope
            slope4 = (holdp2y1-app.holdp1y1) / (holdp2x1-app.holdp1x1);
            % For perpendicular line, slope = -1/slope
            slope5 = -1/slope4;
            ahold2 = [app.holdp1x1 app.holdp1y1];
            bhold2 = [holdp2x1 holdp2y1];
           xint1 = ( 0 - midX2 ) / slope5 + midY2; %x intersection on y axis
            p1x2 = linspace(-norm( ahold2 - bhold2 ) , midX2 + norm( ahold2 - bhold2 )); % when bisector not infinite (1) and slope != 0
            p1y2 = slope5 * (p1x2 - midX2) + midY2;
%error check
            if slope5 == 1 % If the bisector is infinite (y axis)
	            p1x2 = midY2 - norm( ahold2 - bhold2 ) : midY2 + norm( ahold2 - bhold2 ); % Defines y to range vertically
                   p1x2 = zeros( 1, length(p1y2) );
                    for i = 1:length(p1x2)
        	            p1x2(i) = midx2; % x coordinates are just the midpoint's x, along the entire bisector
                    end
                    xint3 = midX2;
            
            elseif slope3 == 0 % If the bisector is infinite-flat on (x axis)
	                p1x2 = midX2 - norm( ahold2 - bhold2 ) : midX2 + norm( ahold2 - bhold2 );
                      p1y2 = zeros( 1, length(p1x2) );
                        for i = 1:length(p1y2)
        		            p1y2(i) = midY2; 
                       end
                    xint3 = nan;
            end
            app.hideL3 = plot(app.UIAxes,p1x2, p1y2, 'b--', 'LineWidth', 2);  
              axis equal
              pause(pauseno)


% find int
            P7 = [p1x1(1),p1y1(1)];
            P8 = [p1x1(end),p1y1(end)];
            P9 = [p1x2(1),p1y2(1)];
            P10 = [p1x2(end),p1y2(end)];

              [f5, g5, x05, y05] = app.line_exp2par_2d( P7 , P8 );
              [f6, g6, x06, y06] = app.line_exp2par_2d( P9 , P10 );

 [ t1x, t2y, pint ] = app.lines_par_int_2d(f5, g5, x05, y05, f6, g6, ...
 x06, y06 );

plot(app.UIAxes,pint(1,1),pint(1,2), 'bx', 'LineWidth', 2);  
              axis equal
pause(pauseno)


% q4.3.2
        % plot 3rd circ

            %define theta range
%             theta = 0:0.01:2*pi;
            %define center coordinates and radius
            radius3 = sqrt((holdp2x1-pint(1,1))^2+(holdp2x1-pint(1,2))^2); 
            xCent3 = pint(1,1);
            yCent3 = pint(1,2);
            %define parametric equation
            anim = animatedline(app.UIAxes,"Color",'cyan',"LineWidth",2);
            for theta = 0:0.01:2*pi
            app.xCoord3 =  xCent3 +  radius3*cos(theta);
            app.yCoord3 =  yCent3 +  radius3*sin(theta);
             addpoints(anim, app.xCoord3, app.yCoord3);
             drawnow
%             app.c = plot(app.UIAxes, xCoord3, yCoord3, 'b', 'LineWidth',2);
            axis equal
            end
% pause(pauseno)

% q5
%         int 3rd circ -> circ 1:2
%         [ num_int5, pint1 ] = app.circles_imp_int_2d( r1, center1, r2, center2 );
            center1 = [xCent3 yCent3];
            center2 = [app.xCent app.yCent];
            center3 = [app.xCent1 app.yCent1];

        [ num_int5, pint1 ] = app.circles_imp_int_2d( radius3, center1, app.radius, center2 );
        [ num_int6, pint2 ] = app.circles_imp_int_2d( radius3, center1, app.dist, center3 );
            plot(app.UIAxes,pint1(1,1),pint1(1,2), pint1(2,1),pint1(2,2),'x', 'LineWidth',2);
            pause(pauseno)
            plot(app.UIAxes,pint2(1,1),pint2(1,2), pint2(2,1),pint2(2,2),'x', 'LineWidth',2);
pause(pauseno)
       app.viewst = false;


%        q6
%           translate -> rotate
  
            dxt = -pint(1,1); %translate 3rd circ centre to origin
            dyt = -pint(1,2);
            pts = [pint(1,1);pint(1,2)];
            % create a 2D Translation in dx, dy NEED TO MAKE A HOMOGENEOUS COORDS matrix
            trans = [1 0 dxt;0 1 dyt; 0 0 1];
            % Make PTS HOMOGENEOUS
            homogeneous_pts = [pts; ones(1,3)];
            trans_pts = trans*homogeneous_pts;
            plot(app.UIAxes,trans_pts(1,1:end),trans_pts(2,1:end),'gx');


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function Button_2Pushed(app, event)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            prompt = {'Enter filename:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'20','hsv'}; 
            app.answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.arrmat = [app.x(1) app.y(1); app.x(2) app.y(2); ...
                app.x(3) app.y(3); ...
                app.x1(1) app.y1(1); app.x1(2) app.y1(2); ...
                app.x1(3) app.y1(3); ];
                app.filename = [app.answer{1} '.txt'];
            writematrix(app.arrmat,app.filename)
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
             cla(app.UIAxes, 'reset')
            
            filename1 = uigetfile();
            app.loadm = readmatrix(filename1);
            app.viewst = true;
 %recall -> 
            app.RunConstructionButtonPushed(app.loadm)

        end

        % Button pushed function: MultiviewwindowButton
        function MultiviewwindowButtonPushed(app, event)
%             prompt = {'Enter Z^:'};
%             dlgtitle = 'Input';
%             dims = [1 35];
%             definput = {'20','hsv'}; 
%             answer2 = inputdlg(prompt,dlgtitle,dims,definput);
%             plot3(app.UIFigure,[app.p1x(1),app.p1y(1)], [app.p1x(end),app.p1y(end)], [1,answer2{1}], 'b--');
%             grid on; axis equal; xlabel('x'); ylabel('y'); zlabel('z'); 
%             subplot(app.UIAxes, app.xCoord3, app.yCoord3, 'b', 'LineWidth',2);
%             subplot(app.UIAxes,app.xCoord1,app.yCoord1, 'g', 'LineWidth',2);
%             subplot(app.UIAxes,app.xCoord,app.yCoord, 'r', 'LineWidth',2);
%             t = tiledlayout(app.UIAxes);
%             % Tile 1
%             ax1 = nexttile(t);
%             plot(ax1,app.xCoord3, app.yCoord3, 'b', 'LineWidth',2);
%             % Tile 2
%             ax2 = nexttile(t);
%             contour(ax2,app.xCoord1,app.yCoord1, 'g', 'LineWidth',2);
%             % Tile 3
%             ax3 = nexttile(t);
%             contour(ax3,app.xCoord,app.yCoord, 'r', 'LineWidth',2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Geometric Plots')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.HideButton = uibutton(app.UIFigure, 'push');
            app.HideButton.ButtonPushedFcn = createCallbackFcn(app, @HideButtonPushed, true);
            app.HideButton.Text = 'Hide';
            app.RevealButton = uibutton(app.UIFigure, 'push');
            app.RevealButton.ButtonPushedFcn = createCallbackFcn(app, @RevealButtonPushed, true);
            app.RevealButton.Text = 'Reveal';
            app.Inputfor1stand2ndcircleButton = uibutton(app.UIFigure, 'push');
            app.Inputfor1stand2ndcircleButton.ButtonPushedFcn = createCallbackFcn(app, @Inputfor1stand2ndcircleButtonPushed, true);
            app.Inputfor1stand2ndcircleButton.Text = 'Input for 1st and 2nd circle';
            app.Inputfor3rdcircleButton = uibutton(app.UIFigure, 'push');
            app.Inputfor3rdcircleButton.ButtonPushedFcn = createCallbackFcn(app, @Inputfor3rdcircleButtonPushed, true);
            app.Inputfor3rdcircleButton.Text = 'Input for 3rd circle';
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @RunConstructionButtonPushed, true);
            app.RunConstructionButton.Text = 'Run Construction';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.ReplayButton = uibutton(app.UIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.MultiviewwindowButton = uibutton(app.UIFigure, 'push');
            app.MultiviewwindowButton.ButtonPushedFcn = createCallbackFcn(app, @MultiviewwindowButtonPushed, true);
            app.MultiviewwindowButton.Text = 'Multi-view window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end